classdef SqueakPeekStudio < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SqueakPeekStudioUIFigure  matlab.ui.Figure
        ButtonsMenuPanel          matlab.ui.container.Panel
        ButtonsMenuGrid           matlab.ui.container.GridLayout
        InfoButton                matlab.ui.control.Button
        SettingsButton            matlab.ui.control.Button
        ExportButton              matlab.ui.control.Button
        VideoSyncButton           matlab.ui.control.Button
        LabelEditButton           matlab.ui.control.Button
        DetectionButton           matlab.ui.control.Button
        VisualizationButton       matlab.ui.control.Button
        DataInputButton           matlab.ui.control.Button
        TabGroup                  matlab.ui.container.TabGroup
        InfoTab                   matlab.ui.container.Tab
        InfoPanel                 matlab.ui.container.Panel
        InfoGrid                  matlab.ui.container.GridLayout
        Button2                   matlab.ui.control.Button
        Button                    matlab.ui.control.Button
        InfoLabel                 matlab.ui.control.Label
        SettingsTab               matlab.ui.container.Tab
        SettingsPanel             matlab.ui.container.Panel
        SettingsGrid              matlab.ui.container.GridLayout
        SettingsLabel             matlab.ui.control.Label
        ExportTab                 matlab.ui.container.Tab
        ExportPanel               matlab.ui.container.Panel
        ExportGrid                matlab.ui.container.GridLayout
        ExportLabel               matlab.ui.control.Label
        VideoSyncTab              matlab.ui.container.Tab
        VideoSyncPanel            matlab.ui.container.Panel
        VideoSyncGrid             matlab.ui.container.GridLayout
        VideoSyncLabel            matlab.ui.control.Label
        LabelEditTab              matlab.ui.container.Tab
        LabelEditPanel            matlab.ui.container.Panel
        LabelEditGrid             matlab.ui.container.GridLayout
        LabelEdit                 matlab.ui.control.Label
        DetectionTab              matlab.ui.container.Tab
        DetectionPanel            matlab.ui.container.Panel
        DetectionGrid             matlab.ui.container.GridLayout
        DetectionLabel            matlab.ui.control.Label
        VisualizationTab          matlab.ui.container.Tab
        VisualizationPanel        matlab.ui.container.Panel
        VisualizationGrid         matlab.ui.container.GridLayout
        VisualizationLabel        matlab.ui.control.Label
        DataInputTab              matlab.ui.container.Tab
        DataInputPanel            matlab.ui.container.Panel
        DataInputGrid             matlab.ui.container.GridLayout
        SelectthedatawhichyouwanttoanalyzeLabel  matlab.ui.control.Label
    end


    properties (Access = private)
        Theme % Description
        ButtonsMenu
        ButtonsOther
        Grids
        Labels
    end

    methods (Access = private)

        function resetMenuButtonColor(app)
            for b = app.ButtonsMenu
                b.BackgroundColor = app.menuNotActiveColor;
            end
        end

        function applyTheme(app, themeStruct)
            app.Theme = themeStruct;
            fig = app.SqueakPeekStudioUIFigure;
            fig.Color = themeStruct.Background;

            for i = 1:numel(app.ButtonsMenu)
                app.ButtonsMenu(i).BackgroundColor = themeStruct.Background;
                app.ButtonsMenu(i).FontColor = themeStruct.Text;
            end

            for i = 1:numel(app.ButtonsOther)
                app.ButtonsOther(i).BackgroundColor = themeStruct.Primary;
                app.ButtonsOther(i).FontColor = themeStruct.Text;
            end
                    
            for i = 1:numel(app.Grids)
                app.Grids(i).BackgroundColor = themeStruct.Background;
            end
            for i = 1:numel(app.Labels)
                app.Labels(i).FontColor = themeStruct.Text;
            end


            app.ButtonsMenuGrid.BackgroundColor = themeStruct.Primary;
        end


        function initMenuButtons(app)
            app.DataInputButton.UserData = app.DataInputTab;
            app.VisualizationButton.UserData = app.VisualizationTab;
            app.DetectionButton.UserData = app.DetectionTab;
            app.LabelEditButton.UserData = app.LabelEditTab;
            app.VideoSyncButton.UserData = app.VideoSyncTab;
            app.ExportButton.UserData = app.ExportTab;
            app.SettingsButton.UserData = app.SettingsTab;
            app.InfoButton.UserData = app.InfoTab;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            disp("Welcome to Squeak Peek Studio by Antonín Gazda");

            % define components
            app.ButtonsMenu = [app.DataInputButton, app.VisualizationButton, app.DetectionButton, ...
                app.LabelEditButton, app.VideoSyncButton, app.ExportButton, ...
                app.SettingsButton, app.InfoButton];

            allButtons = findall(app.SqueakPeekStudioUIFigure, 'Type', 'uibutton');
            app.ButtonsOther = setdiff(allButtons, app.ButtonsMenu);

            app.Grids = findall(app.SqueakPeekStudioUIFigure, 'Type', 'uigridlayout');
            app.Labels = findall(app.SqueakPeekStudioUIFigure, 'Type', 'uilabel');

            initMenuButtons(app);  %initialize UserData
            applyTheme(app, ThemeColors.Light);

            % Select default tab
            app.HandleMenuButtons(struct("Source", app.DataInputButton));
        end

        % Button pushed function: DataInputButton, DetectionButton, 
        % ...and 6 other components
        function HandleMenuButtons(app, event)
            clickedButton = event.Source;
            % Reset all buttons to the inactive color

            for b = app.ButtonsMenu
                b.BackgroundColor = app.Theme.Background;
            end

            % Highlight the clicked button
            clickedButton.BackgroundColor = app.Theme.Accent;

            % Switch to the tab associated with this button
            app.TabGroup.SelectedTab = clickedButton.UserData;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.SqueakPeekStudioUIFigure = uifigure('Visible', 'off');
            app.SqueakPeekStudioUIFigure.Name = 'Squeak Peek Studio';
            app.SqueakPeekStudioUIFigure.Icon = fullfile(pathToMLAPP, 'assets', 'logo', 'squeakpeak_logo.png');
            app.TabGroup = uitabgroup(app.SqueakPeekStudioUIFigure);
            app.DataInputTab = uitab(app.TabGroup);
            app.DataInputTab.Title = 'Data Input';
            app.DataInputPanel = uipanel(app.DataInputTab);
            app.DataInputGrid = uigridlayout(app.DataInputPanel);
            app.SelectthedatawhichyouwanttoanalyzeLabel = uilabel(app.DataInputGrid);
            app.SelectthedatawhichyouwanttoanalyzeLabel.Text = 'Select the data, which you want to analyze';
            app.VisualizationTab = uitab(app.TabGroup);
            app.VisualizationTab.Title = 'Visualization';
            app.VisualizationPanel = uipanel(app.VisualizationTab);
            app.VisualizationGrid = uigridlayout(app.VisualizationPanel);
            app.VisualizationLabel = uilabel(app.VisualizationGrid);
            app.VisualizationLabel.Text = 'Visualization';
            app.DetectionTab = uitab(app.TabGroup);
            app.DetectionTab.Title = 'Detection';
            app.DetectionPanel = uipanel(app.DetectionTab);
            app.DetectionGrid = uigridlayout(app.DetectionPanel);
            app.DetectionLabel = uilabel(app.DetectionGrid);
            app.DetectionLabel.Text = 'Detection';
            app.LabelEditTab = uitab(app.TabGroup);
            app.LabelEditTab.Title = 'Label Edit';
            app.LabelEditPanel = uipanel(app.LabelEditTab);
            app.LabelEditGrid = uigridlayout(app.LabelEditPanel);
            app.LabelEdit = uilabel(app.LabelEditGrid);
            app.LabelEdit.Text = 'Label Edit';
            app.VideoSyncTab = uitab(app.TabGroup);
            app.VideoSyncTab.Title = 'Video Sync';
            app.VideoSyncPanel = uipanel(app.VideoSyncTab);
            app.VideoSyncGrid = uigridlayout(app.VideoSyncPanel);
            app.VideoSyncLabel = uilabel(app.VideoSyncGrid);
            app.VideoSyncLabel.Text = 'Video Sync';
            app.ExportTab = uitab(app.TabGroup);
            app.ExportTab.Title = 'Export';
            app.ExportPanel = uipanel(app.ExportTab);
            app.ExportGrid = uigridlayout(app.ExportPanel);
            app.ExportLabel = uilabel(app.ExportGrid);
            app.ExportLabel.Text = 'Export';
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.SettingsPanel = uipanel(app.SettingsTab);
            app.SettingsGrid = uigridlayout(app.SettingsPanel);
            app.SettingsLabel = uilabel(app.SettingsGrid);
            app.SettingsLabel.Text = 'Settings';
            app.InfoTab = uitab(app.TabGroup);
            app.InfoTab.Title = 'Info';
            app.InfoPanel = uipanel(app.InfoTab);
            app.InfoGrid = uigridlayout(app.InfoPanel);
            app.InfoLabel = uilabel(app.InfoGrid);
            app.InfoLabel.Text = 'Info';
            app.Button = uibutton(app.InfoGrid, 'push');
            app.Button2 = uibutton(app.InfoGrid, 'push');
            app.Button2.Text = 'Button2';
            app.ButtonsMenuPanel = uipanel(app.SqueakPeekStudioUIFigure);
            app.ButtonsMenuGrid = uigridlayout(app.ButtonsMenuPanel);
            app.DataInputButton = uibutton(app.ButtonsMenuGrid, 'push');
            app.DataInputButton.ButtonPushedFcn = createCallbackFcn(app, @HandleMenuButtons, true);
            app.DataInputButton.Text = 'Data Input';
            app.VisualizationButton = uibutton(app.ButtonsMenuGrid, 'push');
            app.VisualizationButton.ButtonPushedFcn = createCallbackFcn(app, @HandleMenuButtons, true);
            app.VisualizationButton.Text = 'Visualization';
            app.DetectionButton = uibutton(app.ButtonsMenuGrid, 'push');
            app.DetectionButton.ButtonPushedFcn = createCallbackFcn(app, @HandleMenuButtons, true);
            app.DetectionButton.Text = 'Detection';
            app.LabelEditButton = uibutton(app.ButtonsMenuGrid, 'push');
            app.LabelEditButton.ButtonPushedFcn = createCallbackFcn(app, @HandleMenuButtons, true);
            app.LabelEditButton.Text = 'LabelEdit';
            app.VideoSyncButton = uibutton(app.ButtonsMenuGrid, 'push');
            app.VideoSyncButton.ButtonPushedFcn = createCallbackFcn(app, @HandleMenuButtons, true);
            app.VideoSyncButton.Text = 'Video Sync';
            app.ExportButton = uibutton(app.ButtonsMenuGrid, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @HandleMenuButtons, true);
            app.ExportButton.Text = 'Export';
            app.SettingsButton = uibutton(app.ButtonsMenuGrid, 'push');
            app.SettingsButton.ButtonPushedFcn = createCallbackFcn(app, @HandleMenuButtons, true);
            app.SettingsButton.Text = 'Settings';
            app.InfoButton = uibutton(app.ButtonsMenuGrid, 'push');
            app.InfoButton.ButtonPushedFcn = createCallbackFcn(app, @HandleMenuButtons, true);
            app.InfoButton.Text = 'Info';
            % Show the figure after all components are created
            app.SqueakPeekStudioUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SqueakPeekStudio

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SqueakPeekStudioUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SqueakPeekStudioUIFigure)
        end
    end
end